classdef VibLoggerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SensorConfigTable               matlab.ui.control.Table
        SamplingfrequencyHzEditFieldLabel  matlab.ui.control.Label
        SamplingFrequencyField          matlab.ui.control.NumericEditField
        FilesizeupdatetimesLabel        matlab.ui.control.Label
        BlockSizeField                  matlab.ui.control.NumericEditField
        RecordingtimesEditField_2Label  matlab.ui.control.Label
        RecordingTimeField              matlab.ui.control.NumericEditField
        LivePreview                     matlab.ui.control.CheckBox
        SaveData                        matlab.ui.control.CheckBox
        MeasurementnameEditFieldLabel   matlab.ui.control.Label
        MeasurementName                 matlab.ui.control.EditField
        DevicesListBoxLabel             matlab.ui.control.Label
        DevicesListBox                  matlab.ui.control.ListBox
        RunSwitchLabel                  matlab.ui.control.Label
        RunSwitch                       matlab.ui.control.Switch
        RecordingLamp                   matlab.ui.control.Lamp
        AddselectedButton               matlab.ui.control.Button
        DatafolderEditFieldLabel        matlab.ui.control.Label
        DatafolderEditField             matlab.ui.control.EditField
        BrowseButton                    matlab.ui.control.Button
        LoadsettingsButton              matlab.ui.control.Button
        SavesettingsButton              matlab.ui.control.Button
        MessagesTextAreaLabel           matlab.ui.control.Label
        MessagesTextArea                matlab.ui.control.TextArea
        UpdateTimeField                 matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        LiveTransmiss                   matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        DAQSession;  % Data acquisition session
        settings = struct();    % settings variable for vibLogger
        sensors;    %holds sensors DB
        SelectedRows; % selected rows in table
        TransmissibilityDialog; %app for transmissibility setup
    end
    
    methods (Access = private)
        
        function closeDaqSession(app)
            app.DAQSession.stop();
            app.DAQSession.release();
            app.RecordingLamp.Enable = false;
            app.RunSwitch.Value = 'Off';
            drawnow;            
            app.logMessage('DAQ session stop command sent');
        end
        
        function logMessage(app,message)
            val = app.MessagesTextArea.Value;
            val{end+1} = sprintf('%s',message);
            app.MessagesTextArea.Value = val;
        end
        
    end
    
    methods (Access = public)
        function updateTransmissibility(app, transmissData)
            [r, c] = find(transmissData);   % find "1"s
            
            if(~isempty(r))
                app.settings.inputs = r';
                app.settings.outputs = c';
                app.settings.callback_shortloop = 'live_transmissibility';
            else
                clear app.settings.inputs
                clear app.settings.outputs
                clear app.settings.callback_shortloop
            end
        end
        
        function matrix = getTransmissibilityMatrix(app)
            matrix = zeros(size(app.SensorConfigTable.Data,1));
            if(isfield(app.settings,'inputs'))
                r = app.settings.inputs;
                c = app.settings.outputs;
                matrix(sub2ind(size(matrix),r,c)) = true;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            wkdir = fileparts(mfilename('fullpath'));
            if(endsWith(wkdir,strcat(filesep,'gui'),'IgnoreCase',true))
                addpath(strcat(wkdir,filesep,'..',filesep))
                addpath(wkdir)
            elseif(endsWith(wkdir,strcat(filesep,'vibLogger'),'IgnoreCase',true))
                addpath(wkdir);
                addpath(strcat(wkdir,filesep,'gui'));
            else
                warning('Working from dc.diamond.ac.uk, make sure this is intended')
                addpath('\\dc.diamond.ac.uk\dls\science\users\mca67379\MATLAB\vibLogger');
                addpath('\\dc.diamond.ac.uk\dls\science\users\mca67379\MATLAB\vibLogger\gui');
            end
            
            devices = daq.getDevices;
            app.DevicesListBox.Items = {};
            for d=1:length(devices)
                for s=1:length(devices(d).Subsystems)
                    for c=1:length(devices(d).Subsystems(s).ChannelNames)
                        app.DevicesListBox.Items{end+1} = [devices(d).ID,'/',devices(d).Subsystems(s).ChannelNames{c}];
                    end
                end
            end
            
            % configure sensor config table
            app.sensors = sensors_db('gui');
            app.SensorConfigTable.ColumnFormat{2} = app.sensors.keys;
            app.SensorConfigTable.ColumnFormat{4} = {'IEPE','Voltage'};
            
            
            app.logMessage('Start up completed');
        end

        % Value changed function: RunSwitch
        function RunSwitchValueChanged(app, event)
            value = app.RunSwitch.Value;
            if(strcmp(value,'On'))
                for i=1:size(app.SensorConfigTable.Data(:,1),1)
                    dev = strsplit(app.SensorConfigTable.Data(i,1),'/');
                    app.settings.device_ids{i} = dev(1);
                    app.settings.channels{i} = [dev(2)];
                end

                app.settings.sensorIDs = cellstr(app.SensorConfigTable.Data(:,2))';
                app.settings.channel_names = cellstr(app.SensorConfigTable.Data(:,3))';

                app.settings.channel_type = cellstr(app.SensorConfigTable.Data(:,4))';
                                 
                app.settings.iepe_excitation_current = .004;                         
                        
                app.settings.fsamp = app.SamplingFrequencyField.Value;
                app.settings.recording_time = app.BlockSizeField.Value;
                app.settings.update_time = app.UpdateTimeField.Value;
                app.settings.timeout = app.RecordingTimeField.Value;
                
                app.settings.output_folder = strcat(app.DatafolderEditField.Value,...
                    filesep, app.MeasurementName.Value);
                
                app.settings.live_preview = app.LivePreview.Value;   
                app.settings.save_data = app.SaveData.Value;
                
                %app.settings
                
                if(~isfolder(app.settings.output_folder))
                    mkdir(app.settings.output_folder);
                end
                app.DAQSession = vibLogger(app.settings);
                
                app.RecordingLamp.Enable = true;
                
                t = timer('TimerFcn', @(~,~) app.closeDaqSession,... 
                 'StartDelay',app.settings.timeout);
                start(t);
                app.logMessage('Session start...');
            else
                app.closeDaqSession();
                app.logMessage('Session close...');
            end
        end

        % Button pushed function: AddselectedButton
        function AddselectedButtonPushed(app, event)
            devices = app.DevicesListBox.Value;
            for i=1:size(devices,2)
                tableData(i,:) = [devices(i) "---" "" "IEPE"];
            end
            app.SensorConfigTable.Data = tableData;           
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            f = uigetdir();
            if(~(f == 0))
                app.DatafolderEditField.Value = f;
            end
            drawnow;
            figure(app.UIFigure)
        end

        % Button pushed function: SavesettingsButton
        function SavesettingsButtonPushed(app, event)
            [settings_file, settings_path] = uiputfile('.mat');
            
            if(~(settings_file == 0))
                saved_settings.SensorConfigTable = app.SensorConfigTable.Data;
                saved_settings.DatafolderEditField = app.DatafolderEditField.Value;
                saved_settings.SamplingFrequencyField = app.SamplingFrequencyField.Value;
                saved_settings.BlockSizeField = app.BlockSizeField.Value;
                saved_settings.RecordingTimeField = app.RecordingTimeField.Value;
                saved_settings.MeasurementName = app.MeasurementName.Value;
                
                save(strcat(settings_path,filesep,settings_file),'saved_settings');
                app.logMessage(sprintf('Saved settings to %s%s',settings_path,settings_file));
            end
            drawnow;
            figure(app.UIFigure);
        end

        % Button pushed function: LoadsettingsButton
        function LoadsettingsButtonPushed(app, event)
            [settings_file, settings_path] = uigetfile('.mat');
            if(~(settings_file == 0))
                load(strcat(settings_path,filesep,settings_file),'saved_settings');
                
                app.SensorConfigTable.Data = saved_settings.SensorConfigTable;
                app.DatafolderEditField.Value = saved_settings.DatafolderEditField;
                app.SamplingFrequencyField.Value = saved_settings.SamplingFrequencyField;
                app.BlockSizeField.Value = saved_settings.BlockSizeField;
                app.RecordingTimeField.Value = saved_settings.RecordingTimeField;
                app.MeasurementName.Value = saved_settings.MeasurementName;
                
                app.logMessage(sprintf('Loaded settings from %s%s',settings_path,settings_file));
            end
            drawnow;
            figure(app.UIFigure)
        end

        % Callback function
        function SensorTableContextMenuOpening(app, event)
            
        end

        % Cell selection callback: SensorConfigTable
        function SensorConfigTableCellSelection(app, event)
            app.SelectedRows = event.Indices(1);
        end

        % Callback function
        function DeleteselectedMenuSelected(app, event)
            table_data = app.SensorConfigTable.Data;
            table_data(app.SelectedRows,:) = [];
            app.SensorConfigTable.Data = table_data;
        end

        % Value changed function: LiveTransmiss
        function LiveTransmissValueChanged(app, event)
            value = app.LiveTransmiss.Value;
            if value==1
                app.TransmissibilityDialog = VibLoggerGUITransmissibility_(app, app.SensorConfigTable.Data);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SensorConfigTable = uitable(app.UIFigure);
            app.SensorConfigTable.ColumnName = {'Device / Channel'; 'Sensor ID'; 'Name'; 'Type'};
            app.SensorConfigTable.RowName = {};
            app.SensorConfigTable.ColumnEditable = [false true true true];
            app.SensorConfigTable.CellSelectionCallback = createCallbackFcn(app, @SensorConfigTableCellSelection, true);
            app.SensorConfigTable.Tooltip = {'Double click to edit fields'};
            app.SamplingfrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingfrequencyHzEditFieldLabel.Text = 'Sampling frequency (Hz)';
            app.SamplingFrequencyField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingFrequencyField.Value = 2048;
            app.FilesizeupdatetimesLabel = uilabel(app.UIFigure);
            app.FilesizeupdatetimesLabel.Text = 'File size / update time (s)';
            app.BlockSizeField = uieditfield(app.UIFigure, 'numeric');
            app.BlockSizeField.Value = 60;
            app.RecordingtimesEditField_2Label = uilabel(app.UIFigure);
            app.RecordingtimesEditField_2Label.Text = 'Recording time (s)';
            app.RecordingTimeField = uieditfield(app.UIFigure, 'numeric');
            app.RecordingTimeField.Value = 300;
            app.LivePreview = uicheckbox(app.UIFigure);
            app.LivePreview.Text = 'Live preview';
            app.SaveData = uicheckbox(app.UIFigure);
            app.SaveData.Text = 'Save data';
            app.MeasurementnameEditFieldLabel = uilabel(app.UIFigure);
            app.MeasurementnameEditFieldLabel.Text = 'Measurement name';
            app.MeasurementName = uieditfield(app.UIFigure, 'text');
            app.DevicesListBoxLabel = uilabel(app.UIFigure);
            app.DevicesListBoxLabel.Text = 'Devices';
            app.DevicesListBox = uilistbox(app.UIFigure);
            app.DevicesListBox.Items = {};
            app.DevicesListBox.Multiselect = 'on';
            app.DevicesListBox.Tooltip = {'Press [CTRL] to select multiple devices'};
            app.DevicesListBox.Value = {};
            app.RunSwitchLabel = uilabel(app.UIFigure);
            app.RunSwitchLabel.Text = 'Run';
            app.RunSwitch = uiswitch(app.UIFigure, 'slider');
            app.RunSwitch.ValueChangedFcn = createCallbackFcn(app, @RunSwitchValueChanged, true);
            app.RecordingLamp = uilamp(app.UIFigure);
            app.RecordingLamp.Enable = 'off';
            app.AddselectedButton = uibutton(app.UIFigure, 'push');
            app.AddselectedButton.ButtonPushedFcn = createCallbackFcn(app, @AddselectedButtonPushed, true);
            app.AddselectedButton.Text = 'Add selected';
            app.DatafolderEditFieldLabel = uilabel(app.UIFigure);
            app.DatafolderEditFieldLabel.Text = 'Data folder';
            app.DatafolderEditField = uieditfield(app.UIFigure, 'text');
            app.DatafolderEditField.Editable = 'off';
            app.DatafolderEditField.Value = '.';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.LoadsettingsButton = uibutton(app.UIFigure, 'push');
            app.LoadsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsettingsButtonPushed, true);
            app.LoadsettingsButton.Text = 'Load settings';
            app.SavesettingsButton = uibutton(app.UIFigure, 'push');
            app.SavesettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SavesettingsButtonPushed, true);
            app.SavesettingsButton.Text = 'Save settings';
            app.MessagesTextAreaLabel = uilabel(app.UIFigure);
            app.MessagesTextAreaLabel.Text = 'Messages';
            app.MessagesTextArea = uitextarea(app.UIFigure);
            app.MessagesTextArea.Enable = 'off';
            app.UpdateTimeField = uieditfield(app.UIFigure, 'numeric');
            app.UpdateTimeField.Value = 1;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '/';
            app.LiveTransmiss = uicheckbox(app.UIFigure);
            app.LiveTransmiss.ValueChangedFcn = createCallbackFcn(app, @LiveTransmissValueChanged, true);
            app.LiveTransmiss.Text = 'Live transmissibility';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VibLoggerGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end