classdef VibPlotsGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DumpcacheButton                 matlab.ui.control.Button
        ForceloadallButton              matlab.ui.control.Button
        MessagesPanel                   matlab.ui.container.Panel
        MessagesTextArea                matlab.ui.control.TextArea
        FileselectionPanel              matlab.ui.container.Panel
        I13Button                       matlab.ui.control.Button
        ProcessedmatfileEditField       matlab.ui.control.EditField
        ProcessedmatfileEditFieldLabel  matlab.ui.control.Label
        LoadButton                      matlab.ui.control.Button
        BrowseButton                    matlab.ui.control.Button
        ChopdataPanel                   matlab.ui.container.Panel
        DatetimefilterToDatePicker      matlab.ui.control.DatePicker
        ToDatePickerLabel               matlab.ui.control.Label
        DatetimefilterFromDatePicker    matlab.ui.control.DatePicker
        FromDatePickerLabel             matlab.ui.control.Label
        DatetimefilterCheckBox          matlab.ui.control.CheckBox
        DatetimefilterToHour            matlab.ui.control.EditField
        DatetimefilterFromHour          matlab.ui.control.EditField
        TimedomainPanel                 matlab.ui.container.Panel
        PercentilesEditField            matlab.ui.control.EditField
        PercentilesEditFieldLabel       matlab.ui.control.Label
        StatsTimeslicesEditField        matlab.ui.control.EditField
        TimeslicesEditFieldLabel        matlab.ui.control.Label
        RMSFreqslicesEditField          matlab.ui.control.EditField
        FreqslicesEditFieldLabel        matlab.ui.control.Label
        DistributionDropDown            matlab.ui.control.DropDown
        StatsbyweekdayButton            matlab.ui.control.Button
        StatsbyhourButton               matlab.ui.control.Button
        RMSbyfrequencybandButton        matlab.ui.control.Button
        ProbabilitydistributionsButton  matlab.ui.control.Button
        TimedrivendataButton            matlab.ui.control.Button
        FrequencydomainPanel            matlab.ui.container.Panel
        VCPeaksstatsButton              matlab.ui.control.Button
        VCPeaksButton                   matlab.ui.control.Button
        VCcurvespercentileEditField     matlab.ui.control.EditField
        VCcurvespercentiles01Label      matlab.ui.control.Label
        CoherencethresholdEditField     matlab.ui.control.EditField
        Coherencethreshold01EditFieldLabel  matlab.ui.control.Label
        IntegrateddisplacementDropDown  matlab.ui.control.DropDown
        TransmissibilityButton          matlab.ui.control.Button
        VCcurvesButton                  matlab.ui.control.Button
        IntegrateddisplacementButton    matlab.ui.control.Button
        PSDButton                       matlab.ui.control.Button
        SpectrogramsButton              matlab.ui.control.Button
        CloseallfiguresButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        processed_filename % data file to process
        cached_data % cached data
        beamline_path % loading path
        force_load % do or don't force load all variables
    end
    
    methods (Access = private)
        function runVibPlots_gui(app,plot_type,variables,settings)
            if(isempty(app.processed_filename))
                msgbox('Please select a file first');
            elseif(isempty(app.cached_data))
                msgbox('Please load data first');
            else
                settings.processed_file = app.processed_filename;
                settings.output_file = app.processed_filename;
                settings.plots = {plot_type};
                
                % datetime range filter
                if(app.DatetimefilterCheckBox.Value)
                    % {'02-Oct-2020 11:00','02-Oct-2020 12:00'};
                    date_start = sprintf('%s %s', ...
                        datestr(app.DatetimefilterFromDatePicker.Value,'dd-mmm-yyyy'), ...
                        app.DatetimefilterFromHour.Value);
                    date_end = sprintf('%s %s',...
                        datestr(app.DatetimefilterToDatePicker.Value,'dd-mmm-yyyy'), ...
                        app.DatetimefilterToHour.Value);
                    settings.datetime_range = {date_start, date_end};
                    
                    % bruteforce fix as time slicing is handled in
                    % vibplots.m
                    %app.force_load = true;
                    %app.LoadButtonPushed(app)
                    %app.force_load = false;
                end
                    
                fig = app.UIFigure;
                d = uiprogressdlg(fig,'Title','Please Wait',...
                    'Indeterminate','on', ...
                    'Message','Preparing plot');
  
                add_to_cache(app, variables);
                
                vibPlots(settings, app.cached_data);
                close(d);
            end
        end
        
        function add_to_cache(app, variables)
            % common variables
            variables{end+1} = 'channel_names';
            variables{end+1} = 'rms_disp';
            variables{end+1} = 'acq_times';
            variables{end+1} = 'acq_times_file';

            % build list first, then load
            cache_hits = {};
            cache_miss = {};
            for i=1:length(variables)
                if(isfield(app.cached_data, variables{i}))
                    cache_hits{end+1} = variables{i};
                else
                    cache_miss{end+1} = variables{i};
                end
            end
            if(~isempty(cache_hits))
                app.logMessage(['Cache hit (',sprintf('%s, ',cache_hits{:}),')']);
            end
            
            if(~isempty(cache_miss))
                v = load(app.processed_filename,cache_miss{:});
                for j=1:length(cache_miss)
                    app.cached_data.(cache_miss{j}) = v.(cache_miss{j});
                end
            
                app.logMessage(['Cache miss; loading on demand (',sprintf('%s, ',cache_miss{:}),')']);
           
            end
            
        end
        
        function logMessage(app,message)
            val = app.MessagesTextArea.Value;
            val{end+1} = sprintf('%s',message);
            app.MessagesTextArea.Value = val;
        end
        
        function beamlineButtonPushed(app, src, event, bl_path)
            app.beamline_path = bl_path;
            BrowseButtonPushed(app, event);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % if this was run via the run() command the working directory
            % is \\dc.diamond.ac.uk. Otherwise it's the current working
            % directory

            wkdir = pwd;
            if(endsWith(wkdir,strcat(filesep,'gui'),'IgnoreCase',true))
                addpath(strcat(wkdir,filesep,'..',filesep))
                addpath(wkdir)
            elseif(endsWith(wkdir,strcat(filesep,'vibLogger'),'IgnoreCase',true))
                addpath(wkdir);
                addpath(strcat(wkdir,filesep,'gui'));
            else
                warning('Working from dc.diamond.ac.uk, make sure this is intended')
                addpath('\\dc.diamond.ac.uk\dls\science\users\mca67379\MATLAB\vibLogger');
                addpath('\\dc.diamond.ac.uk\dls\science\users\mca67379\MATLAB\vibLogger\gui');
            end
            
            try
                run('vers.m');
                app.logMessage(sprintf('Viblogger version %s - "%s"',viblogger_version, viblogger_version_name));
            catch e
            end
            
            app.force_load = false;
            
            % add the "quick beamline buttons" programmatically
            s = gui_settings();
            ks = s.buttons_list.keys;
            prev_pos = app.I13Button.Position(1:2);
            button_width = 50;
            button_spacing = 5;
            for i=1:length(s.buttons_list)
                pos(1:2) = prev_pos;
                pos(3) = button_width;
                pos(4) = app.I13Button.Position(4);
                
                b = uibutton(app.UIFigure, ...
                    'Text', s.buttons_list(ks{i}).label,...
                    'Tooltip', s.buttons_list(ks{i}).description, ...
                    'Parent', app.FileselectionPanel, ...
                    'Position', pos);
                b.ButtonPushedFcn = {@app.beamlineButtonPushed, s.buttons_list(ks{i}).path};
                
                prev_pos(1) = b.Position(1) + b.Position(3) + button_spacing;
                prev_pos(2) = b.Position(2);
            end
            
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [fname,pathname] = uigetfile(strcat(app.beamline_path,'*.mat'),'Select processed data file');
            if(~(fname == 0))
                app.processed_filename = strcat(pathname,fname);
                app.ProcessedmatfileEditField.Value = app.processed_filename;
            else
                app.beamline_path = '';
            end
            drawnow;
            figure(app.UIFigure)
        end

        % Button pushed function: TimedrivendataButton
        function TimedrivendataButtonPushed(app, event)
            app.TimedrivendataButton.Enable = false;
            variables = {'p2p_disp'};
            runVibPlots_gui(app, 'time', variables);
            app.TimedrivendataButton.Enable = true;
        end

        % Button pushed function: ProbabilitydistributionsButton
        function ProbabilitydistributionsButtonPushed(app, event)
            app.ProbabilitydistributionsButton.Enable = false;
            settings.prob_chart_distribution = app.DistributionDropDown.Value;
            prob = cell2mat(textscan(app.PercentilesEditField.Value,'%f'));
            settings.prob_threshold = prob;
            variables = {'p2p_disp'};
            runVibPlots_gui(app, 'distributions', variables, settings);
            app.ProbabilitydistributionsButton.Enable = true;
        end

        % Button pushed function: CloseallfiguresButton
        function CloseallfiguresButtonPushed(app, event)
            close all
        end

        % Button pushed function: IntegrateddisplacementButton
        function IntegrateddisplacementButtonPushed(app, event)
            app.IntegrateddisplacementButton.Enable = false;
            settings.integrated_direction = app.IntegrateddisplacementDropDown.Value;
            variables = {'ff','integr_disp'};
            runVibPlots_gui(app, 'integrated', variables, settings);
            app.IntegrateddisplacementButton.Enable = true;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.LoadButton.Enable = false;
            fig = app.UIFigure;
            d = uiprogressdlg(fig,'Title','Please Wait',...
                'Indeterminate','on', ...
                'Message','Loading file');

            %drawnow;
            figure(app.UIFigure);
            
            if(app.force_load)
                app.cached_data = load(app.processed_filename);
                app.logMessage(sprintf('Loading all variables from: %s\n',app.processed_filename));
                app.force_load = false;
            else
                app.cached_data = load(app.processed_filename,'inputs');
                app.logMessage(sprintf('Loading (on demand) from: %s\n',app.processed_filename));
            end
            app.LoadButton.Enable = true;
            
            if(~isfield(app.cached_data,'inputs'))
                app.TransmissibilityButton.Enable = false;
                app.CoherencethresholdEditField.Enable = false;
            end
            close(d);
            
            
            %close(fig);


        end

        % Button pushed function: StatsbyhourButton
        function StatsbyhourButtonPushed(app, event)
            h = cell2mat(textscan(app.StatsTimeslicesEditField.Value,'%f'));
            settings.hour_slices = h;
            runVibPlots_gui(app, 'distributions_hourly', {}, settings);
        end

        % Button pushed function: StatsbyweekdayButton
        function StatsbyweekdayButtonPushed(app, event)
            runVibPlots_gui(app, 'distributions_weekday', {});
        end

        % Button pushed function: RMSbyfrequencybandButton
        function RMSbyfrequencybandButtonPushed(app, event)
            settings.freq_band_slice = eval(app.RMSFreqslicesEditField.Value);
            %settings.freq_band_slice(1) = 1;
            variables = {'ff','integr_disp'};
            runVibPlots_gui(app, 'band_rms', variables, settings);
        end

        % Button pushed function: SpectrogramsButton
        function SpectrogramsButtonPushed(app, event)
            variables = {'psd_vib','freq','ff','psd_vib_disp'};
            runVibPlots_gui(app,'spectrograms', variables);
        end

        % Button pushed function: PSDButton
        function PSDButtonPushed(app, event)
            variables = {'freq','psd_vib','psd_vib_disp','ff'};
            runVibPlots_gui(app,'psd', variables);
        end

        % Button pushed function: VCcurvesButton
        function VCcurvesButtonPushed(app, event)
            prob = cell2mat(textscan(app.VCcurvespercentileEditField.Value,'%f'));
            settings.prob_threshold = prob;
            settings.vc_mode = 'Area';
            variables = {'cf', 'velo_octave_spec'};
            runVibPlots_gui(app,'vc_curves',variables,settings);
        end

        % Button pushed function: TransmissibilityButton
        function TransmissibilityButtonPushed(app, event)
            settings.coherence_filter = str2double(...
                app.CoherencethresholdEditField.Value);
            variables = {'inputs','outputs','transmiss_freq','transmiss','coher'};
            runVibPlots_gui(app,'transmissibility', variables, settings)
        end

        % Button pushed function: VCPeaksButton
        function VCPeaksButtonPushed(app, event)
            variables = {'cf', 'velo_octave_spec'};
            runVibPlots_gui(app,'vc_peak', variables);  
        end

        % Button pushed function: I13Button
        function I13ButtonPushed(app, event)
            app.beamline_path = 'S:\Science\I13\Vibration_Monitoring\Processed_Data\';
            BrowseButtonPushed(app, event);
        end

        % Callback function
        function I14ButtonPushed(app, event)
            app.beamline_path = 'S:\Science\I14\Vibration_Monitoring\Processed_Data\';
            BrowseButtonPushed(app, event);
        end

        % Button pushed function: ForceloadallButton
        function ForceloadallButtonPushed(app, event)
            app.force_load = true;
            app.LoadButtonPushed();
        end

        % Button pushed function: VCPeaksstatsButton
        function VCPeaksstatsButtonPushed(app, event)
            variables = {'cf', 'velo_octave_spec'};
            runVibPlots_gui(app,'vc_stats', variables);  
        end

        % Button pushed function: DumpcacheButton
        function DumpcacheButtonPushed(app, event)
            cache_filename = uiputfile('*.mat','Specify cache dump .mat file','cache.mat');
            if(cache_filename ~= 0)
                c = app.cached_data;
                save(cache_filename, '-struct', 'c');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CloseallfiguresButton = uibutton(app.UIFigure, 'push');
            app.CloseallfiguresButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallfiguresButtonPushed, true);
            app.CloseallfiguresButton.Text = 'Close all figures';
            app.FrequencydomainPanel = uipanel(app.UIFigure);
            app.FrequencydomainPanel.Title = 'Frequency domain';
            app.SpectrogramsButton = uibutton(app.FrequencydomainPanel, 'push');
            app.SpectrogramsButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrogramsButtonPushed, true);
            app.SpectrogramsButton.Text = 'Spectrograms';
            app.PSDButton = uibutton(app.FrequencydomainPanel, 'push');
            app.PSDButton.ButtonPushedFcn = createCallbackFcn(app, @PSDButtonPushed, true);
            app.PSDButton.Text = 'PSD';
            app.IntegrateddisplacementButton = uibutton(app.FrequencydomainPanel, 'push');
            app.IntegrateddisplacementButton.ButtonPushedFcn = createCallbackFcn(app, @IntegrateddisplacementButtonPushed, true);
            app.IntegrateddisplacementButton.Text = 'Integrated displacement';
            app.VCcurvesButton = uibutton(app.FrequencydomainPanel, 'push');
            app.VCcurvesButton.ButtonPushedFcn = createCallbackFcn(app, @VCcurvesButtonPushed, true);
            app.VCcurvesButton.Text = 'VC curves';
            app.TransmissibilityButton = uibutton(app.FrequencydomainPanel, 'push');
            app.TransmissibilityButton.ButtonPushedFcn = createCallbackFcn(app, @TransmissibilityButtonPushed, true);
            app.TransmissibilityButton.Text = 'Transmissibility';
            app.IntegrateddisplacementDropDown = uidropdown(app.FrequencydomainPanel);
            app.IntegrateddisplacementDropDown.Items = {'increasing', 'decreasing'};
            app.IntegrateddisplacementDropDown.Value = 'increasing';
            app.Coherencethreshold01EditFieldLabel = uilabel(app.FrequencydomainPanel);
            app.Coherencethreshold01EditFieldLabel.Text = 'Coherence threshold (0-1)';
            app.CoherencethresholdEditField = uieditfield(app.FrequencydomainPanel, 'text');
            app.CoherencethresholdEditField.Value = '0.5';
            app.VCcurvespercentiles01Label = uilabel(app.FrequencydomainPanel);
            app.VCcurvespercentiles01Label.Text = 'VC curves percentiles (0-1)';
            app.VCcurvespercentileEditField = uieditfield(app.FrequencydomainPanel, 'text');
            app.VCcurvespercentileEditField.Value = '0.5 0.99';
            app.VCPeaksButton = uibutton(app.FrequencydomainPanel, 'push');
            app.VCPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @VCPeaksButtonPushed, true);
            app.VCPeaksButton.Text = 'VC Peaks';
            app.VCPeaksstatsButton = uibutton(app.FrequencydomainPanel, 'push');
            app.VCPeaksstatsButton.ButtonPushedFcn = createCallbackFcn(app, @VCPeaksstatsButtonPushed, true);
            app.VCPeaksstatsButton.Text = 'VC Peaks stats';
            app.TimedomainPanel = uipanel(app.UIFigure);
            app.TimedomainPanel.Title = 'Time domain';
            app.TimedrivendataButton = uibutton(app.TimedomainPanel, 'push');
            app.TimedrivendataButton.ButtonPushedFcn = createCallbackFcn(app, @TimedrivendataButtonPushed, true);
            app.TimedrivendataButton.Text = 'Time-driven data';
            app.ProbabilitydistributionsButton = uibutton(app.TimedomainPanel, 'push');
            app.ProbabilitydistributionsButton.ButtonPushedFcn = createCallbackFcn(app, @ProbabilitydistributionsButtonPushed, true);
            app.ProbabilitydistributionsButton.Text = 'Probability distributions';
            app.RMSbyfrequencybandButton = uibutton(app.TimedomainPanel, 'push');
            app.RMSbyfrequencybandButton.ButtonPushedFcn = createCallbackFcn(app, @RMSbyfrequencybandButtonPushed, true);
            app.RMSbyfrequencybandButton.Text = 'RMS by frequency band';
            app.StatsbyhourButton = uibutton(app.TimedomainPanel, 'push');
            app.StatsbyhourButton.ButtonPushedFcn = createCallbackFcn(app, @StatsbyhourButtonPushed, true);
            app.StatsbyhourButton.Text = 'Stats by hour';
            app.StatsbyweekdayButton = uibutton(app.TimedomainPanel, 'push');
            app.StatsbyweekdayButton.ButtonPushedFcn = createCallbackFcn(app, @StatsbyweekdayButtonPushed, true);
            app.StatsbyweekdayButton.Text = 'Stats by weekday';
            app.DistributionDropDown = uidropdown(app.TimedomainPanel);
            app.DistributionDropDown.Items = {'Lognormal'};
            app.DistributionDropDown.Value = 'Lognormal';
            app.FreqslicesEditFieldLabel = uilabel(app.TimedomainPanel);
            app.FreqslicesEditFieldLabel.Text = 'Freq. slices';
            app.RMSFreqslicesEditField = uieditfield(app.TimedomainPanel, 'text');
            app.RMSFreqslicesEditField.Tooltip = {'Specify a range in Matlab code (e.g. 0:10:100, or [20 100])'};
            app.RMSFreqslicesEditField.Value = '0:50:250';
            app.TimeslicesEditFieldLabel = uilabel(app.TimedomainPanel);
            app.TimeslicesEditFieldLabel.Text = 'Time slices';
            app.StatsTimeslicesEditField = uieditfield(app.TimedomainPanel, 'text');
            app.StatsTimeslicesEditField.Value = '0 3 8 16 24';
            app.PercentilesEditFieldLabel = uilabel(app.TimedomainPanel);
            app.PercentilesEditFieldLabel.Text = 'Percentile(s)';
            app.PercentilesEditField = uieditfield(app.TimedomainPanel, 'text');
            app.PercentilesEditField.Value = '0.99';
            app.ChopdataPanel = uipanel(app.UIFigure);
            app.ChopdataPanel.Title = 'Chop data';
            app.DatetimefilterFromHour = uieditfield(app.ChopdataPanel, 'text');
            app.DatetimefilterFromHour.Value = '00:00';
            app.DatetimefilterToHour = uieditfield(app.ChopdataPanel, 'text');
            app.DatetimefilterToHour.Value = '00:00';
            app.DatetimefilterCheckBox = uicheckbox(app.ChopdataPanel);
            app.DatetimefilterCheckBox.Text = 'Date / time filter';
            app.FromDatePickerLabel = uilabel(app.ChopdataPanel);
            app.FromDatePickerLabel.Text = 'From';
            app.DatetimefilterFromDatePicker = uidatepicker(app.ChopdataPanel);
            app.ToDatePickerLabel = uilabel(app.ChopdataPanel);
            app.ToDatePickerLabel.Text = 'To';
            app.DatetimefilterToDatePicker = uidatepicker(app.ChopdataPanel);
            app.FileselectionPanel = uipanel(app.UIFigure);
            app.FileselectionPanel.Title = 'File selection';
            app.BrowseButton = uibutton(app.FileselectionPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.LoadButton = uibutton(app.FileselectionPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ProcessedmatfileEditFieldLabel = uilabel(app.FileselectionPanel);
            app.ProcessedmatfileEditFieldLabel.Text = 'Processed .mat file';
            app.ProcessedmatfileEditField = uieditfield(app.FileselectionPanel, 'text');
            app.ProcessedmatfileEditField.Enable = 'off';
            app.I13Button = uibutton(app.FileselectionPanel, 'push');
            app.I13Button.ButtonPushedFcn = createCallbackFcn(app, @I13ButtonPushed, true);
            app.I13Button.Text = 'I13';
            app.MessagesPanel = uipanel(app.UIFigure);
            app.MessagesPanel.Title = 'Messages';
            app.MessagesTextArea = uitextarea(app.MessagesPanel);
            app.MessagesTextArea.Enable = 'off';
            app.ForceloadallButton = uibutton(app.UIFigure, 'push');
            app.ForceloadallButton.ButtonPushedFcn = createCallbackFcn(app, @ForceloadallButtonPushed, true);
            app.ForceloadallButton.Text = 'Force load all';
            app.DumpcacheButton = uibutton(app.UIFigure, 'push');
            app.DumpcacheButton.ButtonPushedFcn = createCallbackFcn(app, @DumpcacheButtonPushed, true);
            app.DumpcacheButton.Text = 'Dump cache';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VibPlotsGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end