classdef VibPlotsGUITransmissibility_ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ChannelsTable       matlab.ui.control.Table
        OutputsLabel        matlab.ui.control.Label
        InputsLabel         matlab.ui.control.Label
        LivetransmissibilityinputoutputcontrolLabel  matlab.ui.control.Label
        SaveandcloseButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp; % parent application
        nrchans; %number of channels
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, channels)
            app.CallingApp = mainapp;
            
            % build channels matrix
            app.nrchans = size(channels,1);
            chanLabels = channels(:,3);
            
            % Initialise transmissibility matrix
            initial = getTransmissibilityMatrix(mainapp);
            
            %initial.Properties.RowNames = cellstr(chanLabels);
            %initial.Properties.VariableNames = cellstr(chanLabels);
            
            app.ChannelsTable.Data = initial;
            app.ChannelsTable.ColumnName = cellstr(chanLabels);
            %app.ChannelsTable'ColumnFormat', 'logical'
            app.ChannelsTable.RowName = cellstr(chanLabels);
            app.ChannelsTable.ColumnFormat = repmat({'logical'},1,app.nrchans);
            app.ChannelsTable.ColumnWidth = repmat({50},1,app.nrchans);
            %app.ChannelsTable.RowName = 'numbered';
            set(app.ChannelsTable,'ColumnEditable',true(1, app.nrchans))
        end

        % Cell edit callback: ChannelsTable
        function ChannelsTableEditCell(app, event)
            indices = event.Indices;
            
            % set the whole row to what it was before, except the clicked one (emulates a
            % "radio" button that won't allow you to click the diagonal)
            
            %rowData = zeros(1,app.nrchans);
            rowData = app.ChannelsTable.Data(indices(1),:);
            if(indices(1) ~= indices(2))
                if(app.ChannelsTable.Data(indices(1),indices(2)) == 1)
                    rowData(indices(2)) = 1;
                end
                app.ChannelsTable.Data(indices(1),:) = rowData;
                
                
                drawnow()
            else
                app.ChannelsTable.Data(indices(1),indices(2)) = 0;
            end
        end

        % Button pushed function: SaveandcloseButton
        function SaveandcloseButtonPushed(app, event)
            updateTransmissibility(app.CallingApp, app.ChannelsTable.Data);
            
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ChannelsTable = uitable(app.UIFigure);
            app.ChannelsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ChannelsTable.RowName = {''};
            app.ChannelsTable.CellEditCallback = createCallbackFcn(app, @ChannelsTableEditCell, true);
            app.OutputsLabel = uilabel(app.UIFigure);
            app.OutputsLabel.Text = 'Outputs';
            app.InputsLabel = uilabel(app.UIFigure);
            app.InputsLabel.Text = 'Inputs';
            app.LivetransmissibilityinputoutputcontrolLabel = uilabel(app.UIFigure);
            app.LivetransmissibilityinputoutputcontrolLabel.Text = 'Live transmissibility - input/output control';
            app.SaveandcloseButton = uibutton(app.UIFigure, 'push');
            app.SaveandcloseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandcloseButtonPushed, true);
            app.SaveandcloseButton.Text = 'Save and close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VibPlotsGUITransmissibility_(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end